classdef AreaEntreCurvasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ResultadoTextArea        matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        CalcularAreaButton       matlab.ui.control.Button
        LimiteInferiorEditField  matlab.ui.control.NumericEditField
        EditField4Label          matlab.ui.control.Label
        Label4                   matlab.ui.control.Label
        LimiteSuperiorEditField  matlab.ui.control.NumericEditField
        EditField3Label          matlab.ui.control.Label
        Label3                   matlab.ui.control.Label
        Funcion2EditField        matlab.ui.control.EditField
        EditField2Label          matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        Funcion1EditField        matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function area = calcularAreaEntreCurvas(~, funcion1_str, funcion2_str, a, b)
            % Convertir las cadenas a expresiones simbólicas
            f = str2sym(funcion1_str);
            g = str2sym(funcion2_str);

            % Calcular el área entre las curvas
            area = abs(int(f - g, a, b));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularAreaButton
        function CalcularAreaButtonPushed(app, event)
            funcion1 = app.Funcion1EditField.Value;
            funcion2 = app.Funcion2EditField.Value;
            a = app.LimiteInferiorEditField.Value;
            b = app.LimiteSuperiorEditField.Value;
            try
                area = calcularAreaEntreCurvas(app, funcion1, funcion2, a, b);
                app.ResultadoTextArea.Value = sprintf('El área entre las curvas es: %s', char(area));
                
                % Graficar las funciones
                f = str2sym(funcion1);
                g = str2sym(funcion2);
                fplot(app.UIAxes, f, [a b], 'LineWidth', 2)
                hold(app.UIAxes, 'on')
                fplot(app.UIAxes, g, [a b], 'LineWidth', 2)
                xlabel(app.UIAxes, 'x')
                ylabel(app.UIAxes, 'y')
                title(app.UIAxes, 'Gráfica de las funciones y el área entre ellas')
                legend(app.UIAxes, {'f(x)', 'g(x)'}, 'Location', 'best')
                grid(app.UIAxes, 'on')
                hold(app.UIAxes, 'off')
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Funcion1EditField = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.Funcion2EditField = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.LimiteSuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.LimiteInferiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularAreaButton = uibutton(app.UIFigure, 'push');
            app.CalcularAreaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAreaButtonPushed, true);
            app.CalcularAreaButton.Text = 'Calcular Área';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaEntreCurvasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end