classdef DerivadaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultadoTextArea           matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        CalcularDerivadaButton      matlab.ui.control.Button
        IngreselafuncinfxEditField  matlab.ui.control.EditField
        IngreselafuncinfxEditFieldLabel  matlab.ui.control.Label
        IngreselafuncinfxLabel      matlab.ui.control.Label
    end

   
    methods (Access = private)
        
        function dy_simplificada = calcularDerivadaMejorada(~, f_usuario)
            % Esta función mejorada calcula la derivada de funciones que pueden contener
            % constantes, potencias, funciones trigonométricas y raíces, además de simplificar el resultado.
            
            syms x; % Definir la variable simbólica
            % Convertir la cadena de entrada a una expresión simbólica
            f_simbolica = str2sym(f_usuario);
            
            % Calcular la derivada
            dy = diff(f_simbolica, x);
            
            % Simplificar la derivada utilizando opciones adicionales
            dy_simplificada = simplify(dy, 'Steps', 100, 'Criterion', 'preferReal');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularDerivadaButton
        function CalcularDerivadaButtonPushed(app, event)
            funcion = app.IngreselafuncinfxEditField.Value;
            try
                resultado = calcularDerivadaMejorada(app, funcion);
                app.ResultadoTextArea.Value = char(resultado);
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IngreselafuncinfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinfxLabel.Text = 'Ingrese la función f(x):';
            app.IngreselafuncinfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinfxEditFieldLabel.Text = 'Ingrese la función f(x):';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularDerivadaButton = uibutton(app.UIFigure, 'push');
            app.CalcularDerivadaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDerivadaButtonPushed, true);
            app.CalcularDerivadaButton.Text = 'Calcular Derivada';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DerivadaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end