classdef IntegralesDefinidasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResultadoTextArea             matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        CalcularIntegralButton        matlab.ui.control.Button
        LimiteInferiorEditField       matlab.ui.control.NumericEditField
        LimiteInferiorEditFieldLabel  matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        LimiteSuperiorEditField       matlab.ui.control.NumericEditField
        LimiteSuperiorEditFieldLabel  matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        IngreselafuncinfxEditField    matlab.ui.control.EditField
        IngreselafuncinaintegrarfxEditFieldLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function q = calcularIntegralDefinida(~, funcion_str, x_min, x_max)
            % Convierte la cadena a una función anónima
            fun = str2func(['@(x) ', strrep(funcion_str, '^', '.^')]);
            
            % Evalúa la integral definida
            q = integral(fun, x_min, x_max);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularIntegralButton
        function CalcularIntegralButtonPushed(app, event)
            funcion = app.IngreselafuncinfxEditField.Value;
            x_min = app.LimiteInferiorEditField.Value;
            x_max = app.LimiteSuperiorEditField.Value;
            try
                resultado = calcularIntegralDefinida(app, funcion, x_min, x_max);
                resultado_fraccion = rat(resultado);
                resultado_logaritmo = log(resultado);
                app.ResultadoTextArea.Value = sprintf(['El valor de la integral desde %.2f hasta %.2f es:\n', ...
                                                       'Como fracción: %s\n', ...
                                                       'En forma decimal: %.4f\n', ...
                                                       'Logaritmo natural del resultado: %.4f\n'], ...
                                                       x_min, x_max, resultado_fraccion, resultado, resultado_logaritmo);
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.IngreselafuncinaintegrarfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinaintegrarfxEditFieldLabel.Text = 'Ingrese la función a integrar f(x)';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.LimiteSuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.LimiteSuperiorEditFieldLabel.Text = 'LimiteSuperior';
            app.LimiteSuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.LimiteInferiorEditFieldLabel = uilabel(app.UIFigure);
            app.LimiteInferiorEditFieldLabel.Text = 'LimiteInferior';
            app.LimiteInferiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularIntegralButton = uibutton(app.UIFigure, 'push');
            app.CalcularIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIntegralButtonPushed, true);
            app.CalcularIntegralButton.Text = 'Calcular Integral';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegralesDefinidasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end